classdef adep_batch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ADEP                           matlab.ui.Figure
        SettingsMenu                   matlab.ui.container.Menu
        PropertiesMenu                 matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        HelpMenu_2                     matlab.ui.container.Menu
        ParentDirectoryEditFieldLabel  matlab.ui.control.Label
        ParentDirectoryEditField       matlab.ui.control.EditField
        CalculateButton                matlab.ui.control.Button
        BackgroundNameEditFieldLabel   matlab.ui.control.Label
        BackgroundNameEditField        matlab.ui.control.EditField
        ImageNameEditFieldLabel        matlab.ui.control.Label
        ImageNameEditField             matlab.ui.control.EditField
        BrowseButton                   matlab.ui.control.Button
        ConsoleTextAreaLabel           matlab.ui.control.Label
        ConsoleTextArea                matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        Parameters = call_parameters
        Gausses
        Profiles
        Grids
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize sum stuff here
            app.Parameters = function_parameters;   
            app.Parameters.main_app = app;
            app.ADEP.Name = "ADEP Batch Version";
            log_filepath = "C:\Users\"+getenv('username')+"\Documents\ADEP_for_FRAP";
            if(~isfolder(log_filepath))
                mkdir(log_filepath);
            end
            app.Parameters.log_file = log_filepath+"\ADEP_batch.log";
            app.ConsoleTextArea.Value{1} = 'Go to Settings to adjust calculation methods, then start by clicking -Calculate-';
        end

        % Close request function: ADEP
        function ADEPCloseRequest(app, event)
            delete(app)
            % delete sum stuff here
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.ConsoleTextArea.Value{end+1} = 'Calculation started.';
            drawnow
            app.Parameters.log_file = check_overwrite(app.Parameters.log_file);
            app.Parameters.img_name = app.ImageNameEditField.Value;
            app.Parameters.bckg_name = app.BackgroundNameEditField.Value;
            gather_data_fit(app.ParentDirectoryEditField.Value, app.Parameters);
            app.ConsoleTextArea.Value{end+1} = 'Calculation finished.';
        end

        % Menu selected function: PropertiesMenu
        function PropertiesMenuSelected(app, event)
            options(app);
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.ParentDirectoryEditField.Value = string(uigetdir('C:\'));
        end

        % Menu selected function: HelpMenu_2
        function HelpMenu_2Selected(app, event)
            open(".\resources\userguide.pdf");
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            helpdlg({"ADEP for FRAP","Version 1.0","Author: Evgeny Gorelik","Supervision: Sebastian Seiffert"},"About")
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ADEP = uifigure;
            app.ADEP.Name = 'UI Figure';
            app.ADEP.CloseRequestFcn = createCallbackFcn(app, @ADEPCloseRequest, true);
            app.SettingsMenu = uimenu(app.ADEP);
            app.SettingsMenu.Text = 'Settings';
            app.PropertiesMenu = uimenu(app.SettingsMenu);
            app.PropertiesMenu.MenuSelectedFcn = createCallbackFcn(app, @PropertiesMenuSelected, true);
            app.PropertiesMenu.Text = 'Properties';
            app.HelpMenu = uimenu(app.ADEP);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.HelpMenu_2 = uimenu(app.HelpMenu);
            app.HelpMenu_2.MenuSelectedFcn = createCallbackFcn(app, @HelpMenu_2Selected, true);
            app.HelpMenu_2.Text = 'Help';
            app.ParentDirectoryEditFieldLabel = uilabel(app.ADEP);
            app.ParentDirectoryEditFieldLabel.Text = 'Parent Directory';
            app.ParentDirectoryEditField = uieditfield(app.ADEP, 'text');
            app.CalculateButton = uibutton(app.ADEP, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.BackgroundNameEditFieldLabel = uilabel(app.ADEP);
            app.BackgroundNameEditFieldLabel.Text = 'Background Name';
            app.BackgroundNameEditField = uieditfield(app.ADEP, 'text');
            app.ImageNameEditFieldLabel = uilabel(app.ADEP);
            app.ImageNameEditFieldLabel.Text = 'Image Name';
            app.ImageNameEditField = uieditfield(app.ADEP, 'text');
            app.BrowseButton = uibutton(app.ADEP, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ConsoleTextAreaLabel = uilabel(app.ADEP);
            app.ConsoleTextAreaLabel.Text = 'Console';
            app.ConsoleTextArea = uitextarea(app.ADEP);
        end
    end

    methods (Access = public)

        % Construct app
        function app = adep_batch

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ADEP)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ADEP)
        end
    end
end