classdef options < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptionsUIFigure                 matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        LeicaStringEditFieldLabel       matlab.ui.control.Label
        LeicaStringEditField            matlab.ui.control.EditField
        BrowseButton_3                  matlab.ui.control.Button
        LogFileEditFieldLabel           matlab.ui.control.Label
        LogFileEditField                matlab.ui.control.EditField
        LeicaExtensionEditFieldLabel    matlab.ui.control.Label
        LeicaExtensionEditField         matlab.ui.control.EditField
        ImageExtensionEditFieldLabel    matlab.ui.control.Label
        ImageExtensionEditField         matlab.ui.control.EditField
        FilteringTab                    matlab.ui.container.Tab
        FourierFilterCutoffEditFieldLabel  matlab.ui.control.Label
        FourierFilterCutoffEditField    matlab.ui.control.NumericEditField
        GaussFilterSigmaEditFieldLabel  matlab.ui.control.Label
        GaussFilterSigmaEditField       matlab.ui.control.NumericEditField
        GaussFilterSizeEditFieldLabel   matlab.ui.control.Label
        GaussFilterSizeEditField        matlab.ui.control.NumericEditField
        FilterMethodDropDownLabel       matlab.ui.control.Label
        FilterMethodDropDown            matlab.ui.control.DropDown
        ProfileTab                      matlab.ui.container.Tab
        RadialBinningThresholdEditFieldLabel  matlab.ui.control.Label
        RadialBinningThresholdEditField  matlab.ui.control.NumericEditField
        CenterFindMethodDropDownLabel   matlab.ui.control.Label
        CenterFindMethodDropDown        matlab.ui.control.DropDown
        ProfileCreationMethodDropDownLabel  matlab.ui.control.Label
        ProfileCreationMethodDropDown   matlab.ui.control.DropDown
        MaxCenterShiftEditFieldLabel    matlab.ui.control.Label
        MaxCenterShiftEditField         matlab.ui.control.NumericEditField
        FittingTab                      matlab.ui.container.Tab
        MethodDropDownLabel             matlab.ui.control.Label
        MethodDropDown                  matlab.ui.control.DropDown
        ProfileFittingPanel             matlab.ui.container.Panel
        PowelMethodPrecisionEditFieldLabel  matlab.ui.control.Label
        PowelMethodPrecisionEditField   matlab.ui.control.NumericEditField
        AcceptanceLevelEditFieldLabel   matlab.ui.control.Label
        AcceptanceLevelEditField        matlab.ui.control.NumericEditField
        ProfileFitLabel                 matlab.ui.control.Label
        RegressionLabel                 matlab.ui.control.Label
        FittingMethodDropDownLabel      matlab.ui.control.Label
        FittingMethodDropDown           matlab.ui.control.DropDown
        DiffusionFittingPanel           matlab.ui.container.Panel
        DetermineDimensionalityCheckBox  matlab.ui.control.CheckBox
        DimensionalityEditFieldLabel    matlab.ui.control.Label
        DimensionalityEditField         matlab.ui.control.NumericEditField
        FromEditFieldLabel              matlab.ui.control.Label
        FromEditField                   matlab.ui.control.NumericEditField
        PrognosticProfileFitLabel       matlab.ui.control.Label
        ToEditFieldLabel                matlab.ui.control.Label
        ToEditField                     matlab.ui.control.NumericEditField
        CorrectGroundIntensityCheckBox  matlab.ui.control.CheckBox
        SkipFirstEditFieldLabel         matlab.ui.control.Label
        SkipFirstEditField              matlab.ui.control.NumericEditField
        SimulatedAnnealingPanel         matlab.ui.container.Panel
        DetermineDimensionalityCheckBox_2  matlab.ui.control.CheckBox
        PrognosticProfileFitLabel_2     matlab.ui.control.Label
        CorrectGroundIntensityCheckBox_2  matlab.ui.control.CheckBox
        DimensionalityEditField_2Label  matlab.ui.control.Label
        DimensionalityEditField_2       matlab.ui.control.NumericEditField
        FromEditField_2Label            matlab.ui.control.Label
        FromEditField_2                 matlab.ui.control.NumericEditField
        ToEditField_2Label              matlab.ui.control.Label
        ToEditField_2                   matlab.ui.control.NumericEditField
        SkipFirstEditField_2Label       matlab.ui.control.Label
        SkipFirstEditField_2            matlab.ui.control.NumericEditField
        SubstNumTab                     matlab.ui.container.Tab
        DetSubstanceDropDownLabel       matlab.ui.control.Label
        DetSubstanceDropDown            matlab.ui.control.DropDown
        SubRand                         matlab.ui.container.Panel
        RandomSamplesEditFieldLabel     matlab.ui.control.Label
        RandomSamplesEditField          matlab.ui.control.NumericEditField
        AcceptanceEditFieldLabel        matlab.ui.control.Label
        AcceptanceEditField             matlab.ui.control.NumericEditField
        SubMan                          matlab.ui.container.Panel
        SubstanceNumberEditFieldLabel   matlab.ui.control.Label
        SubstanceNumberEditField        matlab.ui.control.NumericEditField
        SubDet                          matlab.ui.container.Panel
        EvalSampleEditFieldLabel        matlab.ui.control.Label
        EvalSampleEditField             matlab.ui.control.NumericEditField
        AcceptanceEditField_2Label      matlab.ui.control.Label
        AcceptanceEditField_2           matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        Fourier_Cutoff = 20
        Gauss_Sigma = 2
        Gauss_Size = 7
        Radial_Threshold = 20
        Sub_Num = 1
        Powel_Prec = 10^-6
        Reg_Acc = 0.6
        DetSub = 0
        DetSubAcc = 0.8
        Sample_Num = 8
    end

    properties (Access = private)
        MainApp % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % transfer parameters to gui
            app.MainApp = mainapp;
            app.DetermineDimensionalityCheckBox.Value = app.MainApp.Parameters.det_dim;
            DetermineDimensionalityCheckBoxValueChanged(app, 0);
            app.MaxCenterShiftEditField.Value = app.MainApp.Parameters.center_threshold;
            app.SkipFirstEditField.Value = app.MainApp.Parameters.skip;
            app.DetSubstanceDropDown.Value = app.DetSubstanceDropDown.Items{app.MainApp.Parameters.det_sub+1};
            DetSubstanceDropDownValueChanged(app, 0);
            switch app.MainApp.Parameters.use_diff_fit
                case 1
                    if(app.MainApp.Parameters.simulated_annealing == 0)
                        app.MethodDropDown.Value = app.MethodDropDown.Items{1};
                    else
                        app.MethodDropDown.Value = app.MethodDropDown.Items{3};
                    end
                case 0
                    app.MethodDropDown.Value = app.MethodDropDown.Items{2};
            end
            MethodDropDownValueChanged(app, 0);
            app.FilterMethodDropDown.Value = app.MainApp.Parameters.filter;
            app.CenterFindMethodDropDown.Value = app.MainApp.Parameters.center;
            app.ProfileCreationMethodDropDown.Value = app.MainApp.Parameters.profile;
            app.LeicaExtensionEditField.Value = app.MainApp.Parameters.leica_type;
            app.ImageExtensionEditField.Value = app.MainApp.Parameters.img_type;
            app.LeicaStringEditField.Value = app.MainApp.Parameters.leica_name;
            app.LogFileEditField.Value = check_overwrite(app.MainApp.Parameters.log_file);
            app.FourierFilterCutoffEditField.Value = app.MainApp.Parameters.fourier_cutoff;
            app.GaussFilterSigmaEditField.Value = app.MainApp.Parameters.gaussian_sigma;
            app.GaussFilterSizeEditField.Value =  app.MainApp.Parameters.gaussian_size;
            app.RadialBinningThresholdEditField.Value = app.MainApp.Parameters.radial_thresh;
            app.SubstanceNumberEditField.Value = app.MainApp.Parameters.sub_num;
            app.PowelMethodPrecisionEditField.Value = app.MainApp.Parameters.powell_prec;
            app.AcceptanceLevelEditField.Value = app.MainApp.Parameters.reg_acc;
            app.AcceptanceEditField_2.Value = app.MainApp.Parameters.det_sub_acc;
            app.AcceptanceEditField.Value = app.MainApp.Parameters.det_sub_acc;
            app.RandomSamplesEditField.Value = app.MainApp.Parameters.sample_num;
            app.EvalSampleEditField.Value = app.MainApp.Parameters.sample_num;
        end

        % Close request function: OptionsUIFigure
        function OptionsUIFigureCloseRequest(app, event)
            % transfer parameters to app
            if(~isempty(app.LeicaStringEditField.Value))
                app.MainApp.Parameters.leica_name = app.LeicaStringEditField.Value;
            end
            if(~isempty(app.LogFileEditField.Value))
                app.MainApp.Parameters.log_file = app.LogFileEditField.Value;
            end
            app.MainApp.Parameters.fit = app.MethodDropDown.Value;
            app.MainApp.Parameters.skip = app.SkipFirstEditField.Value;
            app.MainApp.Parameters.center_threshold = app.MaxCenterShiftEditField.Value;
            app.MainApp.Parameters.leica_type = app.LeicaExtensionEditField.Value;
            app.MainApp.Parameters.img_type = app.ImageExtensionEditField.Value;
            app.MainApp.Parameters.filter = app.FilterMethodDropDown.Value;
            app.MainApp.Parameters.fit = app.FittingMethodDropDown.Value;
            app.MainApp.Parameters.profile = app.ProfileCreationMethodDropDown.Value;
            app.MainApp.Parameters.center = app.CenterFindMethodDropDown.Value;
            app.MainApp.Parameters.fourier_cutoff = app.FourierFilterCutoffEditField.Value;
            app.MainApp.Parameters.gaussian_sigma = app.GaussFilterSigmaEditField.Value;
            app.MainApp.Parameters.gaussian_size = app.GaussFilterSizeEditField.Value;
            app.MainApp.Parameters.radial_thresh = app.RadialBinningThresholdEditField.Value;
            % set diffusion fitting model parameters
            if(strcmp(app.MethodDropDown.Value,app.MethodDropDown.Items{1}))
                app.MainApp.Parameters.simulated_annealing = 0;
                app.MainApp.Parameters.use_diff_fit = 1;
                app.MainApp.Parameters.det_dim = app.DetermineDimensionalityCheckBox.Value;
                app.MainApp.Parameters.diff_dim = app.DimensionalityEditField.Value;
                app.MainApp.Parameters.prf_fit_from = app.FromEditField.Value;
                app.MainApp.Parameters.prf_fit_to = app.ToEditField.Value;
            elseif(strcmp(app.MethodDropDown.Value,app.MethodDropDown.Items{2}))
                app.MainApp.Parameters.use_diff_fit = 0;
                app.MainApp.Parameters.powell_prec = app.PowelMethodPrecisionEditField.Value;
                app.MainApp.Parameters.reg_acc = app.AcceptanceLevelEditField.Value;
            elseif(strcmp(app.MethodDropDown.Value,app.MethodDropDown.Items{3}))
                app.MainApp.Parameters.simulated_annealing = 1;
                app.MainApp.Parameters.use_diff_fit = 1;
                app.MainApp.Parameters.det_dim = app.DetermineDimensionalityCheckBox_2.Value;
                app.MainApp.Parameters.diff_dim = app.DimensionalityEditField_2.Value;
                app.MainApp.Parameters.prf_fit_from = app.FromEditField_2.Value;
                app.MainApp.Parameters.prf_fit_to = app.ToEditField_2.Value;
            end
            if(strcmp(app.DetSubstanceDropDown.Value,app.DetSubstanceDropDown.Items{1}))
                app.MainApp.Parameters.sub_num = app.SubstanceNumberEditField.Value;
                app.MainApp.Parameters.det_sub = 0;
            elseif(strcmp(app.DetSubstanceDropDown.Value,app.DetSubstanceDropDown.Items{2}))
                app.MainApp.Parameters.det_sub = 1;
                app.MainApp.Parameters.sample_num = app.RandomSamplesEditField.Value;
                app.MainApp.Parameters.det_sub_acc = app.AcceptanceEditField.Value;
            elseif(strcmp(app.DetSubstanceDropDown.Value,app.DetSubstanceDropDown.Items{3}))
                app.MainApp.Parameters.det_sub = 2;
                app.MainApp.Parameters.sample_num = app.EvalSampleEditField.Value;
                app.MainApp.Parameters.det_sub_acc = app.AcceptanceEditField_2.Value;
            end
            delete(app)
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            % select log file location
            filepath = string(uigetdir('C:\'));
            filename = filepath + "\ADEP_batch_001.log";
            i = 1;
            while (isfile(filename))
                i = i + 1;
                filenum = sprintf('%03d',i);
                filename = filepath + "\ADEP_batch_" + filenum + ".log";
            end
            app.LogFileEditField.Value = filename;
        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            % parameters differ dependent on selected fitting method
            value = app.MethodDropDown.Value;
            if(strcmp(value,app.MethodDropDown.Items{1}))
                app.ProfileFittingPanel.Visible = 'off';
                app.SimulatedAnnealingPanel.Visible = 'off';
                app.DiffusionFittingPanel.Visible = 'on';
            end
            if(strcmp(value,app.MethodDropDown.Items{2}))
                app.ProfileFittingPanel.Visible = 'on';
                app.SimulatedAnnealingPanel.Visible = 'off';
                app.DiffusionFittingPanel.Visible = 'off';
            end
            if(strcmp(value,app.MethodDropDown.Items{3}))
                app.ProfileFittingPanel.Visible = 'off';
                app.SimulatedAnnealingPanel.Visible = 'on';
                app.DiffusionFittingPanel.Visible = 'off';
            end
        end

        % Value changed function: DetermineDimensionalityCheckBox
        function DetermineDimensionalityCheckBoxValueChanged(app, event)
            value = app.DetermineDimensionalityCheckBox.Value;
            if(value == 1)
                app.DimensionalityEditField.Editable = 'off';
            end
            if(value == 0)
                app.DimensionalityEditField.Editable = 'on';
            end
            app.MainApp.Parameters.det_dim = value;
        end

        % Value changed function: DetSubstanceDropDown
        function DetSubstanceDropDownValueChanged(app, event)
            value = app.DetSubstanceDropDown.Value;
            if(strcmp(value,app.DetSubstanceDropDown.Items{1}))
                app.SubMan.Visible = 'on';
                app.SubRand.Visible = 'off';
                app.SubDet.Visible = 'off';
            end
            if(strcmp(value,app.DetSubstanceDropDown.Items{2}))
                app.SubMan.Visible = 'off';
                app.SubRand.Visible = 'on';
                app.SubDet.Visible = 'off';
            end
            if(strcmp(value,app.DetSubstanceDropDown.Items{3}))
                app.SubMan.Visible = 'off';
                app.SubRand.Visible = 'off';
                app.SubDet.Visible = 'on';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OptionsUIFigure = uifigure;
            app.OptionsUIFigure.Name = 'Options';
            app.OptionsUIFigure.CloseRequestFcn = createCallbackFcn(app, @OptionsUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.OptionsUIFigure);
            app.GeneralTab = uitab(app.TabGroup);
            app.GeneralTab.Title = 'General';
            app.LeicaStringEditFieldLabel = uilabel(app.GeneralTab);
            app.LeicaStringEditFieldLabel.Text = 'Leica String';
            app.LeicaStringEditField = uieditfield(app.GeneralTab, 'text');
            app.BrowseButton_3 = uibutton(app.GeneralTab, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse';
            app.LogFileEditFieldLabel = uilabel(app.GeneralTab);
            app.LogFileEditFieldLabel.Text = 'Log File';
            app.LogFileEditField = uieditfield(app.GeneralTab, 'text');
            app.LeicaExtensionEditFieldLabel = uilabel(app.GeneralTab);
            app.LeicaExtensionEditFieldLabel.Text = 'Leica Extension';
            app.LeicaExtensionEditField = uieditfield(app.GeneralTab, 'text');
            app.ImageExtensionEditFieldLabel = uilabel(app.GeneralTab);
            app.ImageExtensionEditFieldLabel.Text = 'Image Extension';
            app.ImageExtensionEditField = uieditfield(app.GeneralTab, 'text');
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.Title = 'Filtering';
            app.FourierFilterCutoffEditFieldLabel = uilabel(app.FilteringTab);
            app.FourierFilterCutoffEditFieldLabel.Text = 'Fourier Filter Cutoff';
            app.FourierFilterCutoffEditField = uieditfield(app.FilteringTab, 'numeric');
            app.FourierFilterCutoffEditField.Limits = [1 Inf];
            app.FourierFilterCutoffEditField.Value = 20;
            app.GaussFilterSigmaEditFieldLabel = uilabel(app.FilteringTab);
            app.GaussFilterSigmaEditFieldLabel.Text = 'Gauss Filter Sigma';
            app.GaussFilterSigmaEditField = uieditfield(app.FilteringTab, 'numeric');
            app.GaussFilterSigmaEditField.Limits = [1 Inf];
            app.GaussFilterSigmaEditField.Value = 2;
            app.GaussFilterSizeEditFieldLabel = uilabel(app.FilteringTab);
            app.GaussFilterSizeEditFieldLabel.Text = 'Gauss Filter Size';
            app.GaussFilterSizeEditField = uieditfield(app.FilteringTab, 'numeric');
            app.GaussFilterSizeEditField.Limits = [1 Inf];
            app.GaussFilterSizeEditField.Value = 7;
            app.FilterMethodDropDownLabel = uilabel(app.FilteringTab);
            app.FilterMethodDropDownLabel.Text = 'Filter Method';
            app.FilterMethodDropDown = uidropdown(app.FilteringTab);
            app.FilterMethodDropDown.Items = {'None', 'Gauss', 'Fourier'};
            app.FilterMethodDropDown.Value = 'None';
            app.ProfileTab = uitab(app.TabGroup);
            app.ProfileTab.Title = 'Profile';
            app.RadialBinningThresholdEditFieldLabel = uilabel(app.ProfileTab);
            app.RadialBinningThresholdEditFieldLabel.Text = 'Radial Binning Threshold';
            app.RadialBinningThresholdEditField = uieditfield(app.ProfileTab, 'numeric');
            app.RadialBinningThresholdEditField.Limits = [1 Inf];
            app.RadialBinningThresholdEditField.Value = 20;
            app.CenterFindMethodDropDownLabel = uilabel(app.ProfileTab);
            app.CenterFindMethodDropDownLabel.Text = 'Center Find Method';
            app.CenterFindMethodDropDown = uidropdown(app.ProfileTab);
            app.CenterFindMethodDropDown.Items = {'Circular Center', 'Maximal Value', 'Point Symmetry', 'Discrete Integration', 'Gaussian Fit'};
            app.CenterFindMethodDropDown.Value = 'Circular Center';
            app.ProfileCreationMethodDropDownLabel = uilabel(app.ProfileTab);
            app.ProfileCreationMethodDropDownLabel.Text = 'Profile Creation Method';
            app.ProfileCreationMethodDropDown = uidropdown(app.ProfileTab);
            app.ProfileCreationMethodDropDown.Items = {'Radial Distribution', 'Concentric Sum'};
            app.ProfileCreationMethodDropDown.Value = 'Radial Distribution';
            app.MaxCenterShiftEditFieldLabel = uilabel(app.ProfileTab);
            app.MaxCenterShiftEditFieldLabel.Text = 'Max. Center Shift';
            app.MaxCenterShiftEditField = uieditfield(app.ProfileTab, 'numeric');
            app.MaxCenterShiftEditField.Limits = [0 Inf];
            app.MaxCenterShiftEditField.Value = 30;
            app.FittingTab = uitab(app.TabGroup);
            app.FittingTab.Title = 'Fitting';
            app.MethodDropDownLabel = uilabel(app.FittingTab);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.FittingTab);
            app.MethodDropDown.Items = {'Diffusion Fitting', 'Profile Fitting', 'Simulated Annealing'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Diffusion Fitting';
            app.ProfileFittingPanel = uipanel(app.FittingTab);
            app.ProfileFittingPanel.Title = 'Profile Fitting';
            app.ProfileFittingPanel.Visible = 'off';
            app.PowelMethodPrecisionEditFieldLabel = uilabel(app.ProfileFittingPanel);
            app.PowelMethodPrecisionEditFieldLabel.Text = 'Powel Method Precision';
            app.PowelMethodPrecisionEditField = uieditfield(app.ProfileFittingPanel, 'numeric');
            app.AcceptanceLevelEditFieldLabel = uilabel(app.ProfileFittingPanel);
            app.AcceptanceLevelEditFieldLabel.Text = 'Acceptance Level';
            app.AcceptanceLevelEditField = uieditfield(app.ProfileFittingPanel, 'numeric');
            app.ProfileFitLabel = uilabel(app.ProfileFittingPanel);
            app.ProfileFitLabel.Text = 'Profile Fit';
            app.RegressionLabel = uilabel(app.ProfileFittingPanel);
            app.RegressionLabel.Text = 'Regression';
            app.FittingMethodDropDownLabel = uilabel(app.ProfileFittingPanel);
            app.FittingMethodDropDownLabel.Text = 'Fitting Method';
            app.FittingMethodDropDown = uidropdown(app.ProfileFittingPanel);
            app.FittingMethodDropDown.Items = {'Melder Nead', 'Powell Method'};
            app.FittingMethodDropDown.Value = 'Melder Nead';
            app.DiffusionFittingPanel = uipanel(app.FittingTab);
            app.DiffusionFittingPanel.Title = 'Diffusion Fitting';
            app.DetermineDimensionalityCheckBox = uicheckbox(app.DiffusionFittingPanel);
            app.DetermineDimensionalityCheckBox.ValueChangedFcn = createCallbackFcn(app, @DetermineDimensionalityCheckBoxValueChanged, true);
            app.DetermineDimensionalityCheckBox.Text = 'Determine Dimensionality';
            app.DimensionalityEditFieldLabel = uilabel(app.DiffusionFittingPanel);
            app.DimensionalityEditFieldLabel.Text = 'Dimensionality';
            app.DimensionalityEditField = uieditfield(app.DiffusionFittingPanel, 'numeric');
            app.DimensionalityEditField.Limits = [0 Inf];
            app.DimensionalityEditField.Value = 2;
            app.FromEditFieldLabel = uilabel(app.DiffusionFittingPanel);
            app.FromEditFieldLabel.Text = 'From';
            app.FromEditField = uieditfield(app.DiffusionFittingPanel, 'numeric');
            app.FromEditField.Limits = [1 Inf];
            app.FromEditField.Value = 1;
            app.PrognosticProfileFitLabel = uilabel(app.DiffusionFittingPanel);
            app.PrognosticProfileFitLabel.Text = 'Prognostic Profile Fit:';
            app.ToEditFieldLabel = uilabel(app.DiffusionFittingPanel);
            app.ToEditFieldLabel.Text = 'To';
            app.ToEditField = uieditfield(app.DiffusionFittingPanel, 'numeric');
            app.ToEditField.Limits = [2 Inf];
            app.ToEditField.Value = 3;
            app.CorrectGroundIntensityCheckBox = uicheckbox(app.DiffusionFittingPanel);
            app.CorrectGroundIntensityCheckBox.Text = 'Correct Ground Intensity';
            app.SkipFirstEditFieldLabel = uilabel(app.DiffusionFittingPanel);
            app.SkipFirstEditFieldLabel.Text = 'Skip First';
            app.SkipFirstEditField = uieditfield(app.DiffusionFittingPanel, 'numeric');
            app.SkipFirstEditField.Limits = [1 Inf];
            app.SkipFirstEditField.Value = 6;
            app.SimulatedAnnealingPanel = uipanel(app.FittingTab);
            app.SimulatedAnnealingPanel.Title = 'Simulated Annealing';
            app.DetermineDimensionalityCheckBox_2 = uicheckbox(app.SimulatedAnnealingPanel);
            app.DetermineDimensionalityCheckBox_2.Text = 'Determine Dimensionality';
            app.PrognosticProfileFitLabel_2 = uilabel(app.SimulatedAnnealingPanel);
            app.PrognosticProfileFitLabel_2.Text = 'Prognostic Profile Fit:';
            app.CorrectGroundIntensityCheckBox_2 = uicheckbox(app.SimulatedAnnealingPanel);
            app.CorrectGroundIntensityCheckBox_2.Text = 'Correct Ground Intensity';
            app.DimensionalityEditField_2Label = uilabel(app.SimulatedAnnealingPanel);
            app.DimensionalityEditField_2Label.Text = 'Dimensionality';
            app.DimensionalityEditField_2 = uieditfield(app.SimulatedAnnealingPanel, 'numeric');
            app.DimensionalityEditField_2.Limits = [0 Inf];
            app.DimensionalityEditField_2.Value = 2;
            app.FromEditField_2Label = uilabel(app.SimulatedAnnealingPanel);
            app.FromEditField_2Label.Text = 'From';
            app.FromEditField_2 = uieditfield(app.SimulatedAnnealingPanel, 'numeric');
            app.FromEditField_2.Limits = [1 Inf];
            app.FromEditField_2.Value = 1;
            app.ToEditField_2Label = uilabel(app.SimulatedAnnealingPanel);
            app.ToEditField_2Label.Text = 'To';
            app.ToEditField_2 = uieditfield(app.SimulatedAnnealingPanel, 'numeric');
            app.ToEditField_2.Limits = [2 Inf];
            app.ToEditField_2.Value = 3;
            app.SkipFirstEditField_2Label = uilabel(app.SimulatedAnnealingPanel);
            app.SkipFirstEditField_2Label.Text = 'Skip First';
            app.SkipFirstEditField_2 = uieditfield(app.SimulatedAnnealingPanel, 'numeric');
            app.SkipFirstEditField_2.Limits = [1 Inf];
            app.SkipFirstEditField_2.Value = 6;
            app.SubstNumTab = uitab(app.TabGroup);
            app.SubstNumTab.Title = 'Subst. Num.';
            app.DetSubstanceDropDownLabel = uilabel(app.SubstNumTab);
            app.DetSubstanceDropDownLabel.Text = 'Det. Substance';
            app.DetSubstanceDropDown = uidropdown(app.SubstNumTab);
            app.DetSubstanceDropDown.Items = {'Manually', 'Randomly', 'Deterministic'};
            app.DetSubstanceDropDown.ValueChangedFcn = createCallbackFcn(app, @DetSubstanceDropDownValueChanged, true);
            app.DetSubstanceDropDown.Value = 'Manually';
            app.SubRand = uipanel(app.SubstNumTab);
            app.SubRand.Visible = 'off';
            app.RandomSamplesEditFieldLabel = uilabel(app.SubRand);
            app.RandomSamplesEditFieldLabel.Text = ' Random Samples';
            app.RandomSamplesEditField = uieditfield(app.SubRand, 'numeric');
            app.RandomSamplesEditField.Limits = [1 Inf];
            app.RandomSamplesEditField.Value = 8;
            app.AcceptanceEditFieldLabel = uilabel(app.SubRand);
            app.AcceptanceEditFieldLabel.Text = 'Acceptance';
            app.AcceptanceEditField = uieditfield(app.SubRand, 'numeric');
            app.AcceptanceEditField.Limits = [0 1];
            app.AcceptanceEditField.Value = 0.8;
            app.SubMan = uipanel(app.SubstNumTab);
            app.SubstanceNumberEditFieldLabel = uilabel(app.SubMan);
            app.SubstanceNumberEditFieldLabel.Text = 'Substance Number';
            app.SubstanceNumberEditField = uieditfield(app.SubMan, 'numeric');
            app.SubstanceNumberEditField.Limits = [1 Inf];
            app.SubstanceNumberEditField.Value = 1;
            app.SubDet = uipanel(app.SubstNumTab);
            app.SubDet.Visible = 'off';
            app.EvalSampleEditFieldLabel = uilabel(app.SubDet);
            app.EvalSampleEditFieldLabel.Text = 'Eval Sample';
            app.EvalSampleEditField = uieditfield(app.SubDet, 'numeric');
            app.EvalSampleEditField.Limits = [1 Inf];
            app.EvalSampleEditField.Value = 1;
            app.AcceptanceEditField_2Label = uilabel(app.SubDet);
            app.AcceptanceEditField_2Label.Text = 'Acceptance';
            app.AcceptanceEditField_2 = uieditfield(app.SubDet, 'numeric');
            app.AcceptanceEditField_2.Limits = [0 1];
            app.AcceptanceEditField_2.Value = 0.8;
        end
    end

    methods (Access = public)

        % Construct app
        function app = options(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptionsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptionsUIFigure)
        end
    end
end