classdef vis_create < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FRAP_Visualizer         matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        NewMenu                 matlab.ui.container.Menu
        SettingsMenu            matlab.ui.container.Menu
        PropertiesMenu          matlab.ui.container.Menu
        HelpMenu                matlab.ui.container.Menu
        ExperimentAxes          matlab.ui.control.UIAxes
        ExperimentListBoxLabel  matlab.ui.control.Label
        ExperimentListBox       matlab.ui.control.ListBox
        ImagePanel              matlab.ui.container.Panel
        ImageAxes               matlab.ui.control.UIAxes
        ImageListBoxLabel       matlab.ui.control.Label
        ImageListBox            matlab.ui.control.ListBox
        SigmaPlotPanel          matlab.ui.container.Panel
        SigmaPlot               matlab.ui.control.UIAxes
        AmplitudePlotPanel      matlab.ui.container.Panel
        AmplitudePlot           matlab.ui.control.UIAxes
        DistributionPlotPanel   matlab.ui.container.Panel
        DistributionPlot        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Parameters % Description
        Plot_Mode = 1
    end
    
    methods (Access = public)
        function change_display_mode(app,selected)
            switch selected
                case 1
                    app.ImagePanel.Visible = 'on';
                    app.SigmaPlotPanel.Visible = 'off';
                    app.AmplitudePlotPanel.Visible = 'off';
                    app.DistributionPlotPanel.Visible = 'off';
                case 2
                    app.ImagePanel.Visible = 'off';
                    app.SigmaPlotPanel.Visible = 'on';
                    app.AmplitudePlotPanel.Visible = 'off';
                    app.DistributionPlotPanel.Visible = 'off';
                case 3
                    app.ImagePanel.Visible = 'off';
                    app.SigmaPlotPanel.Visible = 'off';
                    app.AmplitudePlotPanel.Visible = 'on';
                    app.DistributionPlotPanel.Visible = 'off';
                case 4
                    app.ImagePanel.Visible = 'off';
                    app.SigmaPlotPanel.Visible = 'off';
                    app.AmplitudePlotPanel.Visible = 'off';
                    app.DistributionPlotPanel.Visible = 'on';
                otherwise
                    disp('not accessible value')
            end
            app.Plot_Mode = selected;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FRAP_Visualizer.Name = "FRAP Visualizer";
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            [batch_file,batch_path] = uigetfile(".log","Get File","C:\");
            if(~isnumeric(batch_file))
                app.Parameters = get_parameters(batch_path + "\" +batch_file);
                app.ExperimentListBox.Items = app.Parameters.exp_names;
                diff_process(app.Parameters,app,1);
                app.ImageListBox.Items=app.Parameters.image_names{1};
                show_diff_image(app.Parameters,app,1,1);
                sigma_t_plot(app.Parameters,app,1);
                amplitude_t_plot(app.Parameters,app,1);
                distrib_plot(app.Parameters,app,1);
            end
        end

        % Value changed function: ExperimentListBox
        function ExperimentListBoxValueChanged(app, event)
            [~,index] = ismember(app.ExperimentListBox.Value,app.ExperimentListBox.Items);
            diff_process(app.Parameters,app,index);
            app.ImageListBox.Items=app.Parameters.image_names{index};
            show_diff_image(app.Parameters,app,index,1);
            app.ImageListBox.Value = app.Parameters.image_names{index}{1};
            sigma_t_plot(app.Parameters,app,index);
            amplitude_t_plot(app.Parameters,app,index);
            distrib_plot(app.Parameters,app,index);
        end

        % Value changed function: ImageListBox
        function ImageListBoxValueChanged(app, event)
            [~,exp_index] = ismember(app.ExperimentListBox.Value,app.ExperimentListBox.Items);
            if(isempty(exp_index))
                exp_index = 1;
            end
            [~,im_index] = ismember(app.ImageListBox.Value,app.ImageListBox.Items);
            show_diff_image(app.Parameters,app,exp_index,im_index);
        end

        % Menu selected function: PropertiesMenu
        function PropertiesMenuSelected(app, event)
            vis_options(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FRAP_Visualizer = uifigure;
            app.FRAP_Visualizer.Name = 'UI Figure';
            app.FileMenu = uimenu(app.FRAP_Visualizer);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.SettingsMenu = uimenu(app.FRAP_Visualizer);
            app.SettingsMenu.Text = 'Settings';
            app.PropertiesMenu = uimenu(app.SettingsMenu);
            app.PropertiesMenu.MenuSelectedFcn = createCallbackFcn(app, @PropertiesMenuSelected, true);
            app.PropertiesMenu.Text = 'Properties';
            app.HelpMenu = uimenu(app.FRAP_Visualizer);
            app.HelpMenu.Text = 'Help';
            app.ExperimentAxes = uiaxes(app.FRAP_Visualizer);
            title(app.ExperimentAxes, 'Experiment')
            xlabel(app.ExperimentAxes, 'time [s]')
            ylabel(app.ExperimentAxes, 'distance to center [\mum]')
            app.ExperimentListBoxLabel = uilabel(app.FRAP_Visualizer);
            app.ExperimentListBoxLabel.Text = 'Experiment';
            app.ExperimentListBox = uilistbox(app.FRAP_Visualizer);
            app.ExperimentListBox.Items = {};
            app.ExperimentListBox.ValueChangedFcn = createCallbackFcn(app, @ExperimentListBoxValueChanged, true);
            app.ExperimentListBox.Value = {};
            app.ImagePanel = uipanel(app.FRAP_Visualizer);
            app.ImagePanel.Visible = 'off';
            app.ImageAxes = uiaxes(app.ImagePanel);
            title(app.ImageAxes, 'Image')
            xlabel(app.ImageAxes, 'X')
            ylabel(app.ImageAxes, 'Y')
            app.ImageListBoxLabel = uilabel(app.ImagePanel);
            app.ImageListBoxLabel.Text = 'Image';
            app.ImageListBox = uilistbox(app.ImagePanel);
            app.ImageListBox.Items = {};
            app.ImageListBox.ValueChangedFcn = createCallbackFcn(app, @ImageListBoxValueChanged, true);
            app.ImageListBox.Value = {};
            app.SigmaPlotPanel = uipanel(app.FRAP_Visualizer);
            app.SigmaPlot = uiaxes(app.SigmaPlotPanel);
            title(app.SigmaPlot, 'Sigma vs t-t0 Plot')
            xlabel(app.SigmaPlot, 't-t_0')
            ylabel(app.SigmaPlot, '\sigma^2')
            app.AmplitudePlotPanel = uipanel(app.FRAP_Visualizer);
            app.AmplitudePlot = uiaxes(app.AmplitudePlotPanel);
            title(app.AmplitudePlot, 'log log t-t0 vs. A')
            xlabel(app.AmplitudePlot, 'log(t-t_0)')
            ylabel(app.AmplitudePlot, 'log(A)')
            app.DistributionPlotPanel = uipanel(app.FRAP_Visualizer);
            app.DistributionPlot = uiaxes(app.DistributionPlotPanel);
            title(app.DistributionPlot, 'Diff. Coef. Distribution')
            xlabel(app.DistributionPlot, 'diff. coef.')
            ylabel(app.DistributionPlot, 'weight')
        end
    end

    methods (Access = public)

        % Construct app
        function app = vis_create

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FRAP_Visualizer)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FRAP_Visualizer)
        end
    end
end