classdef vis_options < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Options                      matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        SingleCompTab                matlab.ui.container.Tab
        ImageVisualizeButtonGroup    matlab.ui.container.ButtonGroup
        differenceimageButton_2      matlab.ui.control.RadioButton
        sigmavstt0plotButton         matlab.ui.control.RadioButton
        amplitudevstt0plotButton     matlab.ui.control.RadioButton
        MultipleCompTab              matlab.ui.container.Tab
        ImageVisualizeButtonGroup_2  matlab.ui.container.ButtonGroup
        differenceimageButton        matlab.ui.control.RadioButton
        diffcoefdistributionButton   matlab.ui.control.RadioButton
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.MainApp = mainapp;
            app.Options.Name = "Options";
        end

        % Selection changed function: ImageVisualizeButtonGroup
        function ImageVisualizeButtonGroupSelectionChanged(app, event)
            selectedButton = app.ImageVisualizeButtonGroup.SelectedObject;
            switch selectedButton
                case app.ImageVisualizeButtonGroup.Buttons(1)
                    app.MainApp.change_display_mode(1);
                case app.ImageVisualizeButtonGroup.Buttons(2)
                    app.MainApp.change_display_mode(2);
                case app.ImageVisualizeButtonGroup.Buttons(3)
                    app.MainApp.change_display_mode(3);
                otherwise 
                    disp('not available')
            end
        end

        % Selection changed function: ImageVisualizeButtonGroup_2
        function ImageVisualizeButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.ImageVisualizeButtonGroup_2.SelectedObject;
            switch selectedButton
                case app.ImageVisualizeButtonGroup_2.Buttons(1)
                    app.MainApp.change_display_mode(1);
                case app.ImageVisualizeButtonGroup_2.Buttons(2)
                    app.MainApp.change_display_mode(4);
                otherwise 
                    disp('not available')
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            switch selectedTab
                case app.TabGroup.Children(1)
                    app.ImageVisualizeButtonGroupSelectionChanged(0);
                case app.TabGroup.Children(2)
                    app.ImageVisualizeButtonGroup_2SelectionChanged(0);
                otherwise
                    disp('error')
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Options = uifigure;
            app.Options.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.Options);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.SingleCompTab = uitab(app.TabGroup);
            app.SingleCompTab.Title = 'Single Comp.';
            app.ImageVisualizeButtonGroup = uibuttongroup(app.SingleCompTab);
            app.ImageVisualizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ImageVisualizeButtonGroupSelectionChanged, true);
            app.ImageVisualizeButtonGroup.Title = 'Image Visualize';
            app.differenceimageButton_2 = uiradiobutton(app.ImageVisualizeButtonGroup);
            app.differenceimageButton_2.Text = 'difference image';
            app.differenceimageButton_2.Value = true;
            app.sigmavstt0plotButton = uiradiobutton(app.ImageVisualizeButtonGroup);
            app.sigmavstt0plotButton.Text = 'sigma vs. t-t0 plot';
            app.amplitudevstt0plotButton = uiradiobutton(app.ImageVisualizeButtonGroup);
            app.amplitudevstt0plotButton.Text = 'amplitude vs t-t0 plot';
            app.MultipleCompTab = uitab(app.TabGroup);
            app.MultipleCompTab.Title = 'Multiple Comp.';
            app.ImageVisualizeButtonGroup_2 = uibuttongroup(app.MultipleCompTab);
            app.ImageVisualizeButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @ImageVisualizeButtonGroup_2SelectionChanged, true);
            app.ImageVisualizeButtonGroup_2.Title = 'Image Visualize';
            app.differenceimageButton = uiradiobutton(app.ImageVisualizeButtonGroup_2);
            app.differenceimageButton.Text = 'difference image';
            app.differenceimageButton.Value = true;
            app.diffcoefdistributionButton = uiradiobutton(app.ImageVisualizeButtonGroup_2);
            app.diffcoefdistributionButton.Text = 'diff. coef. distribution';
        end
    end

    methods (Access = public)

        % Construct app
        function app = vis_options(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Options)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Options)
        end
    end
end